classdef Momenty_przebiegow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        wykresA                     matlab.ui.control.UIAxes
        workspaceB                  matlab.ui.control.Button
        plikB                       matlab.ui.control.Button
        wart_srednia_L              matlab.ui.control.Label
        WartoredniokwadratowaLabel  matlab.ui.control.Label
        WartoskutecznaLabel         matlab.ui.control.Label
        WariancjaLabel              matlab.ui.control.Label
        OdchyleniestandardoweLabel  matlab.ui.control.Label
        w_sredniaL                  matlab.ui.control.Label
        w_srkwadrL                  matlab.ui.control.Label
        w_skutecznaL                matlab.ui.control.Label
        wariancjaL                  matlab.ui.control.Label
        odchylenieL                 matlab.ui.control.Label
        stacjonarnoscL              matlab.ui.control.Label
        WczytajprzebiegLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        data;
        ilosc_czesci = 5;
        data_podzielona;
        srednia = zeros(5,1);
        srkwadr = zeros(5,1);
        skuteczna = zeros(5,1);
        wariancja = zeros(5,1);
        odchylenie = zeros(5,1);
        srednia_calosc;
        srednia_k_calosc;
        w_skut_calosc;
        wariancja_calosc;
        odchylenie_calosc;
        prog_dol = 0.95;
        prog_gora = 1.05;
        przebieg_max;
        przebieg_min;
        przebieg_zakres;
        
    end

    methods (Access = private)
    
        function dzieleniePrzebiegu(app)
            plot(app.wykresA, app.data);
            ilosc_w_dziale = ceil(length(app.data)/app.ilosc_czesci);
            app.data_podzielona = zeros(app.ilosc_czesci,ilosc_w_dziale);
            suma = 0;
            for i = 1:app.ilosc_czesci-1
                for j = 1:ilosc_w_dziale
                    suma = suma+1;
                    app.data_podzielona(i,j) = app.data(suma);
                end
                app.srednia(i,1) = sredniaFunkcja(app, app.data_podzielona(i,:));
            end
            for j = 1:ilosc_w_dziale
                tempor = length(app.data)-ilosc_w_dziale+j;
                app.data_podzielona(app.ilosc_czesci,j) = app.data(tempor);
            end
            app.przebieg_max = max(app.data);
            app.przebieg_min = min(app.data);
            app.przebieg_zakres = app.przebieg_max - app.przebieg_min;
        end


        function wynikSr = sredniaFunkcja(app, zadany_ciag)
            suma = 0;
            for i = 1:length(zadany_ciag)
                suma = suma+zadany_ciag(i);
            end
            wynikSr = suma/length(zadany_ciag);
        end


        function wynikSrK = srKwadrFunkcja(app, zadany_ciag)
            ciag_tymcz = zadany_ciag.^2;
            suma = 0;
            for i = 1:length(zadany_ciag)
                suma = suma+ciag_tymcz(i);
            end
            wynikSrK = suma/length(zadany_ciag);
        end


        function wynikSk = wartSkutecznaFunkcja(app, zadany_ciag)
            ciag_tymcz = srKwadrFunkcja(app, zadany_ciag);
            wynikSk = sqrt(ciag_tymcz);
        end


        function wynikWariancja = wariancjaFunkcja(app, zadany_ciag)
            wart_srk = srKwadrFunkcja(app, zadany_ciag);
            suma = 0;
            for i = 1:length(zadany_ciag)
                suma = suma+(zadany_ciag(i)-wart_srk)^2;
            end
            wynikWariancja = suma/length(zadany_ciag);
        end


        function wynikOdchylenie = odchylenieFunkcja(app, zadany_ciag)
            wart_war = wariancjaFunkcja(app, zadany_ciag);
            wynikOdchylenie = sqrt(wart_war);
        end


        function wynikStacjonarnosc = stacjonarnoscFunkcja(app)
            stacjonarnosc = 0;
            wartoscSt = zeros(5:app.ilosc_czesci);
            for i = 1:app.ilosc_czesci
                wartoscSt(1,i) = sredniaFunkcja(app, app.data_podzielona(i,:));
                wartoscSt(2,i) = srKwadrFunkcja(app, app.data_podzielona(i,:));
                wartoscSt(3,i) = wartSkutecznaFunkcja(app, app.data_podzielona(i,:));
                wartoscSt(4,i) = wariancjaFunkcja(app, app.data_podzielona(i,:));
                wartoscSt(5,i) = odchylenieFunkcja(app, app.data_podzielona(i,:));
                
            end
            
            progi = zeros(5,2);
            
            progi(1,1) = wartoscSt(1,1)-(1-app.prog_dol)*app.przebieg_zakres;
            progi(1,2) = wartoscSt(1,1)+(app.prog_gora-1)*app.przebieg_zakres;
            progi(2,1) = wartoscSt(2,1)-(1-app.prog_dol)*app.przebieg_zakres;
            progi(2,2) = wartoscSt(2,1)+(app.prog_gora-1)*app.przebieg_zakres;
            progi(3,1) = wartoscSt(3,1)-(1-app.prog_dol)*app.przebieg_zakres;
            progi(3,2) = wartoscSt(3,1)+(app.prog_gora-1)*app.przebieg_zakres;
            progi(4,1) = wartoscSt(4,1)-(1-app.prog_dol)*app.przebieg_zakres;
            progi(4,2) = wartoscSt(4,1)+(app.prog_gora-1)*app.przebieg_zakres;
            progi(5,1) = wartoscSt(5,1)-(1-app.prog_dol)*app.przebieg_zakres;
            progi(5,2) = wartoscSt(5,1)+(app.prog_gora-1)*app.przebieg_zakres;
                     
            for i=1:app.ilosc_czesci
                    if (wartoscSt(1,i) > progi(1,1) && wartoscSt(1,i) < progi(1,2) && wartoscSt(2,i) > progi(2,1) && wartoscSt(2,i) < progi(2,2) && wartoscSt(3,i) > progi(3,1) && wartoscSt(3,i) < progi(3,2) && wartoscSt(4,i) > progi(4,1) && wartoscSt(4,i) < progi(4,2) && wartoscSt(3,i) < progi(3,2) && wartoscSt(5,i) > progi(5,1) && wartoscSt(5,i) < progi(5,2))

                    else
                        stacjonarnosc = 1;
                    end
            end
            
            
            if stacjonarnosc == 0
                wynikStacjonarnosc = 1;
            else
                wynikStacjonarnosc = 0;
            end
        end


        function onClickCalculation(app, dane)
            app.srednia_calosc = sredniaFunkcja(app,dane);
            app.w_sredniaL.Text = sprintf('%0.7f',app.srednia_calosc);
            app.srednia_k_calosc = srKwadrFunkcja(app,dane);
            app.w_srkwadrL.Text = sprintf('%0.7f',app.srednia_k_calosc);
            app.w_skut_calosc = wartSkutecznaFunkcja(app,dane);
            app.w_skutecznaL.Text = sprintf('%0.7f',app.w_skut_calosc);
            app.wariancja_calosc = wariancjaFunkcja(app,dane);
            app.wariancjaL.Text = sprintf('%0.7f',app.wariancja_calosc);
            app.odchylenie_calosc = odchylenieFunkcja(app,dane);
            app.odchylenieL.Text = sprintf('%0.7f',app.odchylenie_calosc);
            stacj = stacjonarnoscFunkcja(app);
            if stacj == 0
                app.stacjonarnoscL.Text = 'Przebieg nie jest stacjonarny';
            else
                app.stacjonarnoscL.Text = 'Przebieg jest stacjonarny';
            end
        end


    end


    methods (Access = private)

        % Button pushed function: workspaceB
        function workspaceBButtonPushed(app, event)
            try
                app.data = evalin('base','przebieg');
                app.stacjonarnoscL.Text = 'Przetwarzanie danych';
                dzieleniePrzebiegu(app);
                onClickCalculation(app, app.data);
            catch
                app.stacjonarnoscL.Text = 'Przebieg musi nazywać się "przebieg"';
            end
        end

        % Button pushed function: plikB
        function plikBButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.mat');
                filename=[path file];
                if size(file ~= 0)    
                    %przebieg_z_pliku = fullfile(path,file);
                    %app.data = evalin(caller,'przebieg');
                    app.stacjonarnoscL.Text = 'Przetwarzanie danych';
                    tempw = importdata(filename);
                    if isa(tempw,'struct')
                        app.data = tempw.przebieg;
                    else
                        app.data = tempw;
                    end
                    dzieleniePrzebiegu(app);
                    onClickCalculation(app, app.data);
                else
                    app.stacjonarnoscL.Text = 'Przebieg nieprawidłowy';
                end
            catch ME
                app.stacjonarnoscL.Text = 'Wybrany przebieg jest nieprawidłowy';
                disp(ME);
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.wykresA = uiaxes(app.UIFigure);
            title(app.wykresA, 'Wczytany przebieg')
            xlabel(app.wykresA, 'Próbki')
            ylabel(app.wykresA, 'Wartość')
            app.workspaceB = uibutton(app.UIFigure, 'push');
            app.workspaceB.ButtonPushedFcn = createCallbackFcn(app, @workspaceBButtonPushed, true);
            app.workspaceB.Text = 'Przestrzeń robocza';
            app.plikB = uibutton(app.UIFigure, 'push');
            app.plikB.ButtonPushedFcn = createCallbackFcn(app, @plikBButtonPushed, true);
            app.plikB.Text = 'Plik';
            app.wart_srednia_L = uilabel(app.UIFigure);
            app.wart_srednia_L.Text = 'Wartość średnia:';
            app.WartoredniokwadratowaLabel = uilabel(app.UIFigure);
            app.WartoredniokwadratowaLabel.Text = 'Wartość średniokwadratowa:';
            app.WartoskutecznaLabel = uilabel(app.UIFigure);
            app.WartoskutecznaLabel.Text = 'Wartość skuteczna:';
            app.WariancjaLabel = uilabel(app.UIFigure);
            app.WariancjaLabel.Text = 'Wariancja:';
            app.OdchyleniestandardoweLabel = uilabel(app.UIFigure);
            app.OdchyleniestandardoweLabel.Text = 'Odchylenie standardowe:';
            app.w_sredniaL = uilabel(app.UIFigure);
            app.w_sredniaL.Text = '';
            app.w_srkwadrL = uilabel(app.UIFigure);
            app.w_srkwadrL.Text = '';
            app.w_skutecznaL = uilabel(app.UIFigure);
            app.w_skutecznaL.Text = '';
            app.wariancjaL = uilabel(app.UIFigure);
            app.wariancjaL.Text = '';
            app.odchylenieL = uilabel(app.UIFigure);
            app.odchylenieL.Text = '';
            app.stacjonarnoscL = uilabel(app.UIFigure);
            app.stacjonarnoscL.Text = '';
            app.WczytajprzebiegLabel = uilabel(app.UIFigure);
            app.WczytajprzebiegLabel.Text = {'Wczytaj przebieg. Przebieg musi '; 'być zapisany w przestrzeni roboczej'; '/pliku .mat jako "przebieg"'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Momenty_przebiegow

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end